classdef convolution_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        INPUTEditField       matlab.ui.control.EditField
        INPUTEditFieldLabel  matlab.ui.control.Label
        Button               matlab.ui.control.Button
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_1             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a_dat;
        a_span;% the features of a
        b_dat;
        b_span;
        b_span_flip;
        fig1;
        fig2;
        fig3;
        fig4;
        default_x;
        x_max_span;
        button_pushed_time = 0;
        out_array;
        left_mov;
        a;
        b;
        original_span;
    end
    
    methods (Access = public)

        function [] = Init(app)
                % text data
                % a = 1:5;
                % app.a_span = -3:1;
                % b = 6:11;
                % app.b_span = 1:6;
            % basic settings of the function 
            app.original_span = -30 : 30;

            app.Button.Text = "Input Function A";

        end
        % the function to reverse array B, toggled by Button event;
        function [] = ReverseArrayB(app)
           app.b_dat = fliplr(app.b_dat);
           app.fig2.YData = app.b_dat;
           app.b_span_flip = app.b_span(end:-1:1) * -1;
        end
        % the function to align the right of b with the left of a;
        function [left_mov,right_mov] = AlignArrayAB(app)
            
            app.fig2.YData = MoveArray(app,app.b_dat,left_mov);
        end
        function Move_Sum_Array(app,step)
            b_dat_mov = MoveArray(app,app.b_dat,step);
            app.fig2.YData = b_dat_mov;
            mul_ab_dat = b_dat_mov .* app.a_dat;
            app.fig3.YData = mul_ab_dat;
            ylimix_3 = makeylim(app,mul_ab_dat);
            set(app.UIAxes_3,"ylim",ylimix_3);
            y_height = sum(sum(mul_ab_dat));
            app.out_array(step+app.x_max_span) = y_height;
            app.fig4.YData = app.out_array;
            % app.fig4.YData = app.out_array;
            ylimix_4 = makeylim(app,app.out_array);
            set(app.UIAxes_4,"ylim",ylimix_4);
            text(app.UIAxes_4,step-1,y_height+0.25,num2str(y_height));
        end
        function ylimix = makeylim(app,dat)
            ylimix = [min(0,min(dat))-1,max(dat)*1.2+1];
        end
        % function to move a leftwards or rightwards with filling 0
        function out = MoveArray(app,inp,step)
            if step == 0
                out = inp;
            elseif step < 0
                out = [inp(abs(step)+1:end),zeros(1,abs(step))];
            else
                out = [zeros(1,step),inp(1:length(inp)-step)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
            app.Button.Text = "input function a";
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.button_pushed_time == 0
                app.Button.Text = "Input Function B";
                % function handle of a
                a_func = eval(['@(x)' app.INPUTEditField.Value]);
                app.a = arrayfun(a_func,app.original_span);
                a_notzero = find(app.a ~=0 ); % the domain in which a is nonzero.
                pos_index = [a_notzero(1): a_notzero(end)];
                app.a_span = app.original_span(a_notzero(1)):app.original_span(a_notzero(end));
                app.a = app.a(pos_index);
                  
            elseif app.button_pushed_time == 1
                app.Button.Text = "Reverse Array";
                b_func = eval(['@(x)' app.INPUTEditField.Value]);
                app.b = arrayfun(b_func,app.original_span);
                b_notzero = find(app.b ~=0 ); % the domain in which a is nonzero.
                pos_index = [b_notzero(1): b_notzero(end)];
                app.b_span = app.original_span(b_notzero(1)):app.original_span(b_notzero(end));
                % set the maximum span in x axis
                b_len = length(app.b_span);
                app.b = app.b(pos_index);
                app.x_max_span = max(abs([app.a_span(1)-1,app.a_span(end)+1, ...
                                        app.b_span(1)-1,app.b_span(end)+1, ...
                                        app.a_span(1)-b_len,app.a_span(end)+b_len]));
                app.default_x = -app.x_max_span : app.x_max_span;
                % fill the zeros for the array a;
                app.a_dat = [zeros(1,app.a_span(1)+app.x_max_span),app.a,zeros(1,app.x_max_span - app.a_span(end))];
                app.fig1 = stem(app.UIAxes_1,app.default_x,app.a_dat);
                % fill the zeros for the array b;
                app.b_dat = [zeros(1,app.b_span(1)+app.x_max_span),app.b,zeros(1,app.x_max_span - app.b_span(end))];
                app.fig2 = stem(app.UIAxes_2,app.default_x,app.b_dat);
                % reset the plot area 3 & 4;
                app.fig3 = stem(app.UIAxes_3,app.default_x,zeros(length(app.default_x),1));
                app.fig4 = stem(app.UIAxes_4,app.default_x,zeros(length(app.default_x),1));
                xlim_setting = [-app.x_max_span,app.x_max_span]*1.2;
                set(app.UIAxes_1,'xlim',xlim_setting,'ylim',[min(app.a_dat)-1,max(app.a_dat)+1]);
                set(app.UIAxes_2,'xlim',xlim_setting,'ylim',[min(app.b_dat)-1,max(app.b_dat)+1]);
                set(app.UIAxes_3,'xlim',xlim_setting);
                set(app.UIAxes_4,'xlim',xlim_setting);
                % the array for convolution output
                app.out_array = zeros(length(app.default_x),1);
            elseif app.button_pushed_time == 2
                ReverseArrayB(app);
                app.Button.Text = "Align A & B";
            elseif app.button_pushed_time == 3
                app.left_mov = app.a_span(1) - app.b_span_flip(end)-1; 
                right_mov = app.a_span(end) - app.b_span_flip(1) + 1;
                Move_Sum_Array(app,app.left_mov);
                app.Button.Text = "Move Right";
            else
                Move_Sum_Array(app,app.left_mov + app.button_pushed_time -3);
            end
        app.button_pushed_time = app.button_pushed_time + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.INPUTEditFieldLabel = uilabel(app.UIFigure);
            app.INPUTEditFieldLabel.Text = 'INPUT';
            app.INPUTEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution_display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end