classdef sampling_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OMEGASlider                 matlab.ui.control.Slider
        OMEGASliderLabel            matlab.ui.control.Label
        AMPLPHASButton              matlab.ui.control.Button
        SAMPLButton                 matlab.ui.control.Button
        FREQLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SaveImageButton             matlab.ui.control.Button
        SignalSamplingDisplayLabel  matlab.ui.control.Label
        SAPLFUNCDropDown            matlab.ui.control.DropDown
        SAPLFUNCDropDownLabel       matlab.ui.control.Label
        AudioButton                 matlab.ui.control.Button
        XAxisSlider                 matlab.ui.control.Slider
        XAxisSliderLabel            matlab.ui.control.Label
        SAPLFREQSlider              matlab.ui.control.Slider
        SAPLFREQSliderLabel         matlab.ui.control.Label
        FUNCINPEditField            matlab.ui.control.EditField
        FUNCINPEditFieldLabel       matlab.ui.control.Label
        Button_1                    matlab.ui.control.Button
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        continuous_func; % Description
        plot_func_bound = 1;
        N = 1e6;
        sample_freq;
        freq_scale
        sampling_rate = 44100
    end
    
    methods (Access = public)
        
        function [] = Init(app)
            app.Button_1.Text = "FUNC INP";
            app.AudioButton.Text = "Recording";
            app.SAPLFREQSlider.Limits = [0.1,100];
            app.SAPLFREQSlider.Value = 10;
        end
        function out = ctft(app)
            fun = app.continuous_func;
            ts = 1e3;
            dt = 1/ts;
            t1 = -pi : dt : 0;
            t2 = 0 : dt : pi;
            t = [t1 , t2];
            ft = arrayfun(fun,t); % f(t)
            X_jw = @(w)dt * ft * exp(-1j*w*t)';
            w = -40: 40;
            figure;
            stem(w,abs(arrayfun(X_jw,w)))
        end
        function [] = GetFunction(app)
            func_str = app.FUNCINPEditField.Value;
            app.continuous_func =  eval(['@(x) ' func_str]);
            plot_func_x = linspace(-app.plot_func_bound,app.plot_func_bound,app.N);
            plot_func_y = arrayfun(app.continuous_func,plot_func_x);
            plot(app.UIAxes,plot_func_x,plot_func_y);
        end
        function [sample_dat] = SampleFunc(app,sample_x)
           % FREQ Input Maxium Frequency for FFT plotting
           % sample_x the sampling 
           L = length(sample_x);
           sample_dat = arrayfun(app.continuous_func,sample_x);
           stem(app.UIAxes_2,sample_x,sample_dat);
           % ans = dtft_array(app,-1000:1000,sample_dat,sample_x);
           % N = length(sample_dat);
           % X = fftshift(fft(sample_dat, N));
           f_array = -8 : 0.01 : 8;
           dtft_out = dtft_array(app,f_array,sample_dat);
           % stem(app.UIAxes_4,linspace(-app.sample_freq/2,app.sample_freq/2,N),abs(X)/N);
        end
        function dtft_out = dtft()
            
        end
        function dtft_out = dtft_array(app,f_array,dat)
           n = 1: length(dat);
           dtft_fun = @(f)dat*exp(-1j*f.*n)';
           dtft_out = arrayfun(dtft_fun,f_array);
           dtft_out = abs(dtft_out);
           plot(app.UIAxes_4,f_array,dtft_out); 
        end
        function updateData(app,Data)
            [t_series,value] = Data;
            app.continuous_func = @(t)interp1(t_series,value,t);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            GetFunction(app);
            value = app.SAPLFREQSlider.Value;
            app.sample_freq = value;
            dt = 1/app.sample_freq;
            sample_x = -app.plot_func_bound:dt:app.plot_func_bound;
            sampl_func_plot_time = -3*dt:dt:3*dt;
            stem(app.UIAxes_3,sampl_func_plot_time,ones(length(sampl_func_plot_time),1));
            set(app.UIAxes_3,"ylim",[0,1.2]);
            set(app.UIAxes_3,'xTickLabel',num2str(get(app.UIAxes_3,'xTick')','%.3f'));
            sampl_dat = SampleFunc(app,sample_x);
        end

        % Value changing function: SAPLFREQSlider
        function SAPLFREQSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = fix(changingValue);
            app.Label.Text = num2str(changingValue,"%d");
        end

        % Value changed function: SAPLFREQSlider
        function SAPLFREQSliderValueChanged(app, event)
            value = app.SAPLFREQSlider.Value;
            app.sample_freq = value;
            dt = 1/app.sample_freq;
            sample_x = -app.plot_func_bound:dt:app.plot_func_bound;
            sampl_func_plot_time = -3*dt:dt:3*dt;
            stem(app.UIAxes_3,sampl_func_plot_time,ones(length(sampl_func_plot_time),1));
            set(app.UIAxes_3,"ylim",[0,1.2]);
            set(app.UIAxes_3,'xTickLabel',num2str(get(app.UIAxes_3,'xTick')','%.3f'));
            sampl_dat = SampleFunc(app,sample_x);
        end

        % Value changed function: OMEGASlider
        function OMEGASliderValueChanged(app, event)
            value = app.OMEGASlider.Value;
            
        end

        % Button pushed function: AudioButton
        function AudioButtonPushed(app, event)
            % Specify the name of the app you want to open
        
        % Try to open the app
            % Use appopen to open the app


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.FUNCINPEditFieldLabel = uilabel(app.UIFigure);
            app.FUNCINPEditFieldLabel.Text = 'FUNC INP';
            app.FUNCINPEditField = uieditfield(app.UIFigure, 'text');
            app.SAPLFREQSliderLabel = uilabel(app.UIFigure);
            app.SAPLFREQSliderLabel.Text = 'SAPL FREQ';
            app.SAPLFREQSlider = uislider(app.UIFigure);
            app.SAPLFREQSlider.ValueChangedFcn = createCallbackFcn(app, @SAPLFREQSliderValueChanged, true);
            app.SAPLFREQSlider.ValueChangingFcn = createCallbackFcn(app, @SAPLFREQSliderValueChanging, true);
            app.XAxisSliderLabel = uilabel(app.UIFigure);
            app.XAxisSliderLabel.Text = 'XAxis';
            app.XAxisSlider = uislider(app.UIFigure);
            app.AudioButton = uibutton(app.UIFigure, 'push');
            app.AudioButton.ButtonPushedFcn = createCallbackFcn(app, @AudioButtonPushed, true);
            app.AudioButton.Text = 'Audio';
            app.SAPLFUNCDropDownLabel = uilabel(app.UIFigure);
            app.SAPLFUNCDropDownLabel.Text = 'SAPL FUNC';
            app.SAPLFUNCDropDown = uidropdown(app.UIFigure);
            app.SignalSamplingDisplayLabel = uilabel(app.UIFigure);
            app.SignalSamplingDisplayLabel.Text = 'Signal Sampling Display';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FREQLabel = uilabel(app.UIFigure);
            app.FREQLabel.Text = 'FREQ';
            app.SAMPLButton = uibutton(app.UIFigure, 'push');
            app.SAMPLButton.Text = 'SAMPL';
            app.AMPLPHASButton = uibutton(app.UIFigure, 'push');
            app.AMPLPHASButton.Text = 'AMPL/PHAS';
            app.OMEGASliderLabel = uilabel(app.UIFigure);
            app.OMEGASliderLabel.Text = 'OMEGA';
            app.OMEGASlider = uislider(app.UIFigure);
            app.OMEGASlider.ValueChangedFcn = createCallbackFcn(app, @OMEGASliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sampling_display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end